classdef AppRNI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Button                          matlab.ui.control.Button
        ImageOpenFile                   matlab.ui.control.Image
        SelecioneosarquivosdasmediessLabel  matlab.ui.control.Label
        UIpanel                         matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        MediesTab                       matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        TreeLocalRNI                    matlab.ui.container.CheckBoxTree
        CalcMaisProx                    matlab.ui.control.Button
        LocaisderealizaesdasmediesdeRNILabel  matlab.ui.control.Label
        PA_RNITab                       matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SelecioneosGRUOsdesejadosLabel  matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        DistEditField                   matlab.ui.control.NumericEditField
        DistnciamEditFieldLabel         matlab.ui.control.Label
        CalcularmaioresnveisEVmButton   matlab.ui.control.Button
        SelecioneasLocalidadesdesejadasLabel  matlab.ui.control.Label
        ListBox_GR_UO                   matlab.ui.control.ListBox
        EstaoTab                        matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        ExibirButton                    matlab.ui.control.Button
        TabelaCheckBox                  matlab.ui.control.CheckBox
        MapaCheckBox                    matlab.ui.control.CheckBox
        HTML                            matlab.ui.control.HTML
        PontuaisTab                     matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        InserirnatabelaButton           matlab.ui.control.Button
        MaioresnveisButton_2            matlab.ui.control.Button
        EditFieldUFPont                 matlab.ui.control.EditField
        UFLabel                         matlab.ui.control.Label
        GerarKMLButton                  matlab.ui.control.Button
        EditFieldRaioPont               matlab.ui.control.EditField
        EditFieldLongPont               matlab.ui.control.EditField
        EditFieldLatPont                matlab.ui.control.EditField
        RaioaveriguadoLabel             matlab.ui.control.Label
        LongitudeLabel                  matlab.ui.control.Label
        LatitudeLabel                   matlab.ui.control.Label
        EditFieldServPont               matlab.ui.control.EditField
        EditFieldLocalPont              matlab.ui.control.EditField
        EditFieldNEstPont               matlab.ui.control.EditField
        ServioLabel                     matlab.ui.control.Label
        LocalidadeLabel                 matlab.ui.control.Label
        NdaestaoLabel                   matlab.ui.control.Label
        DemandaspontuaisMPFentidadesexternasetcLabel  matlab.ui.control.Label
        ListaPARNILabel                 matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

   
    properties (Access = private)

        Data_PA_RNI;           %Tabela das estações do PA de RNI
        Data_Meas_Probes;      %Dados das medições obtidos pelas sondas       
        Data_Meas_Cash;        %Guarda todas as infomações da tabela em Cash 
        Data_Meas_Cash_Select; %Guarda os dados seleciondos da tabela em Cash
        Data_Meas_Cash_All     %Guarda todas as infomações Calculadas em Cash       
        EditFieldRow;          %Armazena o número da linha da tabela PA_RNI
        mapAxes;               %Eixo do mapa
        %Estruturas dos Metadados
        metaData = struct('Filename', {}, 'Measures', {}, 'Sensor', {}, 'Data', {}, 'LatitudeLimits', {}, 'LongitudeLimits', {}, 'MetaDataProbe', {})
   
    end 
    
    methods (Access = private)
        
        function Map_Config(app)
          
            %Defini as características do mapa
            app.UIpanel.AutoResizeChildren = 'off';
            app.mapAxes = geoaxes(app.UIpanel, "Units","normalized", "Position",[0 0 1 1 ], "FontSize",9, "Box","off", "TickDir","none", "Grid","off");
            
            %Elimina as informações dos Labels do mapa
            app.mapAxes.LatitudeLabel.String  = '';
            app.mapAxes.LongitudeLabel.String = '';
       
            %Define limtes geográficos com ênfase no mapa do Brasil
            geolimits(app.mapAxes, [-50, 35], [-90, 15]);

            %Configura o toolbar do mapa
            tb = axtoolbar(app.mapAxes,"default");
            addToolbarMapButton(tb, 'basemap');

        end
        
        function Calc_Dist_Emax(app, Dist_Max)

             Dist_Max_Level = Dist_Max; 
            
             % Testa se há algum arquivo de medições de RNI para calular
             try
                Lat_Meas_Probes  = app.Data_Meas_Probes.Latitude;
                Long_Meas_Probes = app.Data_Meas_Probes.Longitude;
            catch
                uialert(app.UIFigure, ['Não foram encontrados arquivos referentes as medições! Insira os arquivos das medições e tente no' ...
                    'vamente!'], 'Go!','Icon','success')
                return
            end

            % Distancia máxima das estação em metros
            dist_EMax = (Dist_Max_Level/111.12)/1000;

            format long;

            for jj=1:height(app.UITable.Data)

                Lat_Estacao   = str2double(app.Data_Meas_Cash_Select.('Latitude da Estação')(jj));
                Long_Estacao  = str2double(app.Data_Meas_Cash_Select.('Longitude da Estação')(jj));

                Lat_Max  = Lat_Estacao + dist_EMax;
                Lat_Min  = Lat_Estacao - dist_EMax;
                Long_Max = Long_Estacao + dist_EMax;
                Long_Min = Long_Estacao - dist_EMax;

                %Filtra de app.Data_Meas_Probes apenas os dados das medições até o raio de distância máxima da estação
                filteredT = app.Data_Meas_Probes((Lat_Meas_Probes >= Lat_Min & Lat_Meas_Probes <= Lat_Max) & (Long_Meas_Probes >= Long_Min & Long_Meas_Probes <= Long_Max), :);
                [~, maxIndex] =  max(filteredT.FieldValue);

                if ~isempty(maxIndex)
                    Lat_filteredT     = filteredT.Latitude;                    
                    Long_filteredT    = filteredT.Longitude;
                    
                    % E_Level_filteredT = filteredT.FieldValue;
                    % dist = distance(Lat_Estacao, Long_Estacao, Lat_filteredT, Long_filteredT)* (pi/180) * 6371*1000;
                    
                    %Realiza os calculos das distâncias entre a coordanda da estação e os pontos das coordenadas das medições do RNI até o raio pré-determinado
                    dist_Meas_St = deg2km(distance(Lat_Estacao, Long_Estacao, Lat_filteredT, Long_filteredT))*1000;

                    %Filtra apenas os pontos de medições do RNI que contenham distância mensor ou igual aom raio pré-determinado
                    Dist_Max_index = find(dist_Meas_St <= Dist_Max_Level);

                    Dist_Max_fT    = filteredT(Dist_Max_index, :);

                    E_Dist_Level   = Dist_Max_fT.FieldValue;
                    Lat_Dist_Max   = Dist_Max_fT.Latitude;                    
                    Long_Dist_Max  = Dist_Max_fT.Longitude;
                    Data_Med_Max   = Dist_Max_fT.Timestamp;

                    %Obtém o indice do FieldMax    
                    [~, maxIndex]  =  max(E_Dist_Level);

                    %Obtém o FielMax e as coordendas e o datatime associados ao FieldMax
                    E_Level_Max   = E_Dist_Level(maxIndex);
                    Lat_Max_Calc  = Lat_Dist_Max(maxIndex);
                    Long_Max_Calc = Long_Dist_Max(maxIndex);
                    Data_Med_Calc = Data_Med_Max(maxIndex);
                    ContMaior14Vm = sum(E_Level_Max >= Dist_Max_Level);

                    % Inseri os valores calculados na Tabela
                    if ~isempty(E_Level_Max)
                        app.UITable.Data.('Emáx (V/m)')(jj)      = E_Level_Max;
                        app.UITable.Data.('Latitude Emáx')(jj)   = num2str(Lat_Max_Calc, '%.8f');
                        app.UITable.Data.('Longitude Emáx')(jj)  = num2str(Long_Max_Calc, '%.8f');
                        app.UITable.Data.('> 14 V/M')(jj)        = ContMaior14Vm;
                        app.UITable.Data.('Data da Medição')(jj) = Data_Med_Calc; 
                        
                        % Inseri os valores calculados na Tabela de Cash
                        app.Data_Meas_Cash_Select.('Emáx (V/m)')(jj)      = E_Level_Max;
                        app.Data_Meas_Cash_Select.('Latitude Emáx')(jj)   = num2str(Lat_Max_Calc, '%.8f');
                        app.Data_Meas_Cash_Select.('Longitude Emáx')(jj)  = num2str(Long_Max_Calc, '%.8f');
                        app.Data_Meas_Cash_Select.('> 14 V/M')(jj)        = ContMaior14Vm;
                        app.Data_Meas_Cash_Select.('Data da Medição')(jj) = Data_Med_Calc;   
                    end             
                end               
            end

            app.UITable.Data = app.UITable.Data(find(~cellfun(@isempty, app.UITable.Data{:, 6})),:);
            app.Data_Meas_Cash_Select = app.Data_Meas_Cash_Select(find(~cellfun(@isempty, app.Data_Meas_Cash_Select{:, 11})),:);
            app.UITable.ColumnEditable = [false false false false false false false false false true true];
           
            %Formata as colunas da tabela com cores
            s = uistyle("BackgroundColor","cyan");
            addStyle(app.UITable,s,"column",5:9)
            s = uistyle("BackgroundColor","yellow");
            addStyle(app.UITable,s,"column",10:11)
            uialert(app.UIFigure, 'Cálculos dos Campos elétricos máximos (EMax) realizados com sucesso!', 'Tudo certo!','Icon','success')
        end
        
        function Format_UITable(app)
            % Criar uma matriz de strings de Vazios e inseri novas colunas na UITable
            matrix_nan       = strings(height(app.UITable.Data), numel(class.Constants.GUINewColumns));
            app.UITable.Data = [app.UITable.Data array2table(matrix_nan)];

            app.UITable.ColumnName = class.Constants.GUIColumnsSelect;
            visibleData = app.UITable.Data(:, [2, 3, 4, 6, 9, 10, 11, 12, 13, 14, 15]);
            app.UITable.Data = visibleData;
            app.UITable.ColumnWidth = class.Constants.GuiColumnWidthCalc;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %Centraliza a Tela do AppRNI ao abrir o aplicativo
            movegui(app.UIFigure, 'center');

            % Inicializa a tela pincipal do AppRNI maximizada
            app.UIFigure.WindowState = 'maximized';
            
            % Insere texto no app.HTML
            app.HTML.HTMLSource = '<!DOCTYPE html><html lang="en"><head><meta charset="UTF-8"><title>Modificar Tabela</title><style>#infoText {text-align: center; font-weight: bold; color: blue;}</style></head><body><p id="infoText">Para visualização das informações da estação monitorada clique em uma das linhas da tabela ao lado:</p></body></html>';
            
            %Insere o título no cabeçário da tabela dos dados do PA_RNI 
            app.ListaPARNILabel.Text = sprintf('Estações do Plano de Fiscalização de RNI do ano de %d',year(datetime('now')));
            
            %Realiza a leitura do arquivo csv do PA_RNI e cria a UITable
            app.Data_PA_RNI        = fcn.ReadFile_PA_RNI(app);
            app.UITable.Data       = app.Data_PA_RNI;
            app.UITable.ColumnName = class.Constants.GUIColumnsAll;

            app.Data_Meas_Cash = app.UITable.Data;

            Format_UITable(app);

            figure(app.UIFigure);           
            
            %Configurações do Mapa
            Map_Config(app);
                       
        end

        % Value changed function: ListBox_GR_UO
        function ListBox_GR_UOValueChanged(app, event)
           
            %Armazena em ER_UO as informações das URs da tabela do PA_RNI  
            ER_UO = app.Data_PA_RNI.('Unidade regional');

            %app.UITable.Data = app.Data_PA_RNI;
            
            %Busca na Base de dados do PA_RNI as localidades da Unidade Operacional selecionada 
            Index_Local            = find(strcmp(ER_UO, app.ListBox_GR_UO.Value));
            app.UITable.Data       =  app.UITable.Data(Index_Local,:);
            app.Data_Meas_Cash_All = app.Data_Meas_Cash(Index_Local,:);
            Localidades            = unique(app.Data_Meas_Cash.Municipio(Index_Local)); 

            delete(app.Tree.Children);
            
            %Escreve na app.Tree as informações relacionadas a localidade correpondente a Unidade Regional selecionada 
            for ii = 1:numel(Localidades)
                uitreenode(app.Tree,"Text",Localidades(ii));
            end
            expand(app.Tree)

        end

        % Button pushed function: CalcularmaioresnveisEVmButton
        function CalcularmaioresnveisEVmButtonPushed(app, event)
      
            Dist_Max_Level = app.DistEditField.Value;
            Calc_Dist_Emax(app, Dist_Max_Level);

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            
            selectedNodes = app.Tree.CheckedNodes;
            
            idxLogical_Cach = [];

            GR_UO_Select     = string(app.ListBox_GR_UO.Value);
            idxLogical_GR_UO = find(strcmp(app.Data_Meas_Cash_All.('Unidade regional'), GR_UO_Select));
            Data_PA_RNI_idx  = app.UITable.Data(idxLogical_GR_UO,:);

            for pp = 1:numel(selectedNodes)
                idxLogical      = find(strcmp(Data_PA_RNI_idx.('Municipio'), string(selectedNodes(pp).Text)));
                idxLogical      = unique([idxLogical_Cach; idxLogical], 'stable');
                idxLogical_Cach = idxLogical;
            end
            if ~isempty(idxLogical_Cach)
                app.UITable.Data          = Data_PA_RNI_idx(idxLogical,:);
                app.Data_Meas_Cash_Select = app.Data_Meas_Cash_All(idxLogical,:);
            else
                app.UITable.Data          = Data_PA_RNI_idx;
                app.Data_Meas_Cash_Select = app.Data_Meas_Cash_All;
            end

        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)

            app.TabGroup.SelectedTab = app.EstaoTab;
            app.EditFieldRow         = event.InteractionInformation.DisplayRow;
            
            htmlContent = sprintf([ ...
                '<!DOCTYPE html><html lang="en"><head>'...
                '<meta charset="UTF-8">'...
                '<meta name="viewport" content="width=device-width, initial-scale=1.0">'...
                '<title>Modificar Tabela</title>'...
                '<style>'...  
                'p.infoText { width: 100%%; text-align: left; font-weight: bold; color: red; margin: 10px 0; font-family: Times New Roman, sans-serif; font-size: 16px; }'... % Centraliza o texto, negrito, vermelho, Arial 12px
                'table { width: 100%%; border-collapse: collapse; margin-bottom: 18px; }'...
                'td { color: blue; font-weight: bold; border: 1px solid black; padding: 6px; text-align: left; }'...
                'td.greenText { color: green; }'... % Classe para texto verde
                '</style>'...
                '</head><body>'...
                '<p class="infoText">Dados da estação do Plano anual do RNI:</p>'...
                '<table>'...
                '<tr><td class="greenText">Estação N º: </td><td>%s</td></tr>'...  % Linha modificada
                '<tr><td class="greenText">Município/UF: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Serviço: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Latitude: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Longitude: </td><td>%s</td></tr>'...
                '</table>'...
                '<p class="infoText">Dados do monitoramento:</p>'...
                '<table>'...
                '<tr><td class="greenText">Data da medição: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Campo maior V/m: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Latitude Maior V/m: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Longitude maior V/m: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Medidas > 14 V/M: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Justificativa de NV: </td><td>%s</td></tr>'...
                '<tr><td class="greenText">Observações: </td><td>%s</td></tr>'...
                '</table>'...
                '</body></html>'], ...
                app.Data_Meas_Cash_Select.('N° da Estacao')(app.EditFieldRow), ...
                strcat(app.Data_Meas_Cash_Select.Municipio(app.EditFieldRow),"/",app.Data_Meas_Cash.UF(app.EditFieldRow)), ...
                app.Data_Meas_Cash_Select.('Serviço')(app.EditFieldRow), ...
                app.Data_Meas_Cash_Select.('Latitude da Estação')(app.EditFieldRow), ...
                app.Data_Meas_Cash_Select.('Longitude da Estação')(app.EditFieldRow), ...
                app.Data_Meas_Cash_Select.('Data da Medição')(app.EditFieldRow), ...
                app.Data_Meas_Cash_Select.('Emáx (V/m)')(app.EditFieldRow), ...
                app.Data_Meas_Cash_Select.('Latitude Emáx')(app.EditFieldRow), ...
                app.Data_Meas_Cash_Select.('Longitude Emáx')(app.EditFieldRow), ...
                app.Data_Meas_Cash_Select.('> 14 V/M')(app.EditFieldRow), ...
                app.Data_Meas_Cash_Select.('Justificativa (apenas NV)')(app.EditFieldRow), ...
                app.Data_Meas_Cash_Select.('Observações importantes')(app.EditFieldRow));
            
                % Use uma string ou char array ao invés de cell array
                app.HTML.HTMLSource = htmlContent;

                %Plota o Mapa com o raio máximo ao redor da estação selecionada
                Lat_Est  = str2double(app.Data_Meas_Cash_Select.('Latitude da Estação')(app.EditFieldRow));
                Long_Est = str2double(app.Data_Meas_Cash_Select.('Longitude da Estação')(app.EditFieldRow));
         
                %Plota o ponto de máximo EMax
                Lat_Est_EMax  =  str2double(string(table2array(app.Data_Meas_Cash_Select(app.EditFieldRow,12))));
                Long_Est_EMax =  str2double(string(table2array(app.Data_Meas_Cash_Select(app.EditFieldRow,13))));

                % geoscatter(app.mapAxes, lat,lon,sizes,colors, "filled")
                % Hold the current plot to add new points without erasing the existing ones
                hold(app.mapAxes, 'on');

                % Antes de ploatar, a gente apaga os plots que não mais
                % fazem sentido.
                plotTag = 'tableSelectionRow';
                delete(findobj(app.mapAxes.Children, 'Tag', plotTag))
                
                % Plot the point station
                geoscatter(app.mapAxes, Lat_Est, Long_Est, 'LineWidth', 4, 'Color', 'red', 'Tag', plotTag);

                 % Plot the point EMax
                geoscatter(app.mapAxes, Lat_Est_EMax, Long_Est_EMax, 'LineWidth', 4, 'Color', 'yellow', 'Tag', plotTag);
                
                % Define limtes geográficos conforme arquivos de meidições
                geolimits(app.mapAxes, [Lat_Est-0.02, Lat_Est+0.02], [Long_Est-0.02, Long_Est+0.02]);

                % Optionally, release the hold state if you want to allow future plots to overwrite
                hold(app.mapAxes, 'off');
                
                drawcircle(app.mapAxes, 'Position', [Lat_Est, Long_Est], ...
                                        'Radius', 0.002, ...
                                        'Color', 'green', ...
                                        'FaceSelectable', 0, ...
                                        'InteractionsAllowed', 'none', ...
                                        'EdgeAlpha', 0, ...
                                        'Tag', plotTag)
            
        end

        % Image clicked function: ImageOpenFile
        function ImageOpenFileClicked2(app, event)
            
            % Armazena as informações de latitude e longitude das estações 
            St_RNI_Lat  = str2double(table2array(app.Data_PA_RNI(:,7)));
            St_RNI_Long = str2double(table2array(app.Data_PA_RNI(:,8)));

            % Abre o diretório dos arquivos das medições das sondas
            initialPath = 'C:\P&D\AppRNI\DataBase\Meas_Sondas';
            
            % Abrir a caixa de diálogo para selecionar múltiplos arquivos
            [fileName, folderName] = uigetfile({'*.txt';'*.csv';'*.mat';'*.*'}, ...
                                              'Selecione arquivos', initialPath, ...
                                              'MultiSelect', 'on');
            %Mantém a Tela principal (figure) em primeiro plano
            figure(app.UIFigure);
            
            % Verifica se algum arquivo foi selecionado
            if isequal(fileName, 0)
                disp('Nenhum arquivo selecionado.');
                return
            end

            % Se apenas um arquivo foi selecionado, filenames será uma string
            % Se múltiplos arquivos foram selecionados, filenames será um cell array
            if ischar(fileName)
                fileName = {fileName};
            end

            fileFullName = {};
            for ii = 1:length(fileName)
                fileFullName{ii} = fullfile(folderName, fileName{ii});
            end
                
            % DESCARTAR OS ARQUIVOS JÁ LIDOS.
            idxExistFile = ~ismember(fileFullName, {app.metaData.Filename});
            fileFullName = fileFullName(idxExistFile);


            % fcn.Loop_ReadFiles(app);
            for pp = 1:numel(fileFullName)
                 % Extrai do arquivo a informação sobre o tipo de sonda
                 % que gerou o arquivo de medição
                 Type_Meas_Probes = fcn.TypeMeasProbe(app, fileFullName{pp});

                 %Lê os dados do arquivo de medição para DataRows
                 app.metaData(end+1) = fcn.ReadFile_Meas_Probes(app, Type_Meas_Probes, fileFullName{pp}, pp, numel(fileFullName));                 
            end
            % Armazenar os dados de medições dos arquivos
            listOfTables = {app.metaData.Data};            
            app.Data_Meas_Probes = sortrows(vertcat(listOfTables{:}), 'Timestamp', 'descend');

            LocalMaisProximo = {};
            for jj = 1:numel(app.metaData)
                
                % Definir o número alvo
                LatitudeLimSt = app.metaData(jj).LatitudeLimits;
                LongitudeLimSt = app.metaData(jj).LongitudeLimits;

                Distance = distance(mean(LatitudeLimSt), mean(LongitudeLimSt), St_RNI_Lat, St_RNI_Long);
                
                % Calcular a diferença entre cada elemento do vetor e o número alvo
                [~, idxMinDistance] = min(abs(Distance));
                
                % Encontrar o valor mais próximo
                LocalMaisProximo{end+1} = sprintf('%s - %s', app.Data_PA_RNI.UF{idxMinDistance}, app.Data_PA_RNI.Municipio{idxMinDistance});
            end 
            LocalMaisProximo = unique(LocalMaisProximo);
            
            %Escreve na app.Tree as informações relacionadas a localidade
            delete(app.TreeLocalRNI.Children);

            for ll = 1:numel(LocalMaisProximo)
                uitreenode(app.TreeLocalRNI, "Text", LocalMaisProximo{ll});
            end
            expand(app.TreeLocalRNI)

            % Hold the current plot to add new points without erasing the existing ones
            hold(app.mapAxes, 'on');

            % Define limtes geográficos conforme arquivos de meidições
            geolimits(app.mapAxes, 'auto')

            % Plot the points Meas
            % geoscatter(app.mapAxes, app.Data_Meas_Probes.Latitude, app.Data_Meas_Probes.Longitude, 10, 'blue', 'filled', 'Tag', 'Route');
            hDistortion = geoscatter(app.mapAxes, app.Data_Meas_Probes.Latitude, app.Data_Meas_Probes.Longitude, [], app.Data_Meas_Probes.FieldValue,  'filled');
            % Optionally, release the hold state if you want to allow future plots to overwrite
            hold(app.mapAxes, 'off');
        
        end

        % Button pushed function: ExibirButton
        function ExibirButtonPushed(app, event)
            if app.TabelaCheckBox.Value && app.MapaCheckBox.Value
                app.UITable.Visible = "on";
                app.UIpanel.Visible = "on";
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UIpanel.Layout.Row    =  2;
                app.UIpanel.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo table
                app.UITable.Layout.Row    =  3;
                app.UITable.Layout.Column =  [2, 3];
            elseif app.TabelaCheckBox.Value
                % Defini a area inical ocupada pelo table
                app.UITable.Layout.Row    =  [2, 3];
                app.UITable.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UIpanel.Visible = "off";
                app.UITable.Visible = "on";
            elseif app.MapaCheckBox.Value
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UIpanel.Layout.Row    =  [2, 3];
                app.UIpanel.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UITable.Visible = "off";
                app.UIpanel.Visible = "on";
            else
                app.UITable.Visible = "on";
                app.UIpanel.Visible = "on";
                % Defini a area inical ocupada pelo uipanel no Grid
                app.UIpanel.Layout.Row    =  [2, 13];
                app.UIpanel.Layout.Column =  [2, 3];
                % Defini a area inical ocupada pelo table
                app.UITable.Layout.Row    =  [14, 19];
                app.UITable.Layout.Column =  [2, 3];
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            d = drawrectangle(app.mapAxes);
            geolimits(app.mapAxes, [d.Position(1), d.Position(1)+d.Position(3)], [d.Position(2), d.Position(2)+d.Position(4)])
            delete(d)

        end

        % Button pushed function: CalcMaisProx
        function CalcMaisProxButtonPushed(app, event)
            
            Dist_Max_Level = 200;
            Calc_Dist_Emax(app, Dist_Max_Level);

        end

        % Callback function: TreeLocalRNI
        function TreeLocalRNICheckedNodesChanged(app, event)
            selectedNodes = app.TreeLocalRNI.CheckedNodes;

            app.UITable.Data = app.Data_PA_RNI;
            
            idxLogical_Cach = [];

            for pp = 1:numel(selectedNodes)
                idxLogical  = find(strcmp(strcat(app.Data_Meas_Cash.UF, " - ", app.Data_Meas_Cash.Municipio), string(selectedNodes(pp).Text)));
                idxLogical  = unique([idxLogical_Cach; idxLogical], 'stable');
                idxLogical_Cach = idxLogical;
            end
            if ~isempty(idxLogical_Cach)
                app.UITable.Data          = app.UITable.Data(idxLogical,:);
                app.Data_Meas_Cash_Select = app.Data_Meas_Cash(idxLogical,:);
            else
                app.UITable.Data          = app.UITable.Data;
                app.Data_Meas_Cash_Select = app.Data_Meas_Cash;
            end

            Format_UITable(app);
            app.CalcMaisProx.Enable = true;
        end

        % Button pushed function: InserirnatabelaButton
        function InserirnatabelaButtonPushed(app, event)
            
            app.UITable.Data = [];
            app.UITable.ColumnName = class.Constants.GUIColumnsSelect;
            app.UITable.Data{1, 1} = app.EditFieldUFPont.Value;
            app.UITable.Data{1, 2} = app.EditFieldLocalPont.Value;
            app.UITable.Data{1, 3} = app.EditFieldServPont.Value;
            app.UITable.Data{1, 4} = app.EditFieldNEstPont.Value;
            app.UITable.Data{1, 5} = '';
            app.UITable.Data{1, 6} = '';
            app.UITable.Data{1, 7} = '';
            app.UITable.Data{1, 8} = '';
            app.UITable.Data{1, 9} = '';
            app.UITable.Data{1, 10} = '';
            app.UITable.Data{1, 11} = '';

            visibleData = app.UITable.Data(:, [3, 4, 5, 7, 10, 11, 12, 13, 14, 15, 16]);
            app.UITable.Data = visibleData;
            app.UITable.ColumnEditable = [false false false false false false false false false true true];
            % app.UITable.BackgroundColor = 'yellow';
            uialert(app.UIFigure, 'Cálculos dos Campos elétricos máximos (EMax) realizados com sucesso!', 'Tudo certo!','Icon','success')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ListaPARNILabel = uilabel(app.GridLayout);
            app.ListaPARNILabel.Text = '';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MediesTab = uitab(app.TabGroup);
            app.MediesTab.Title = 'Medições';
            app.GridLayout6 = uigridlayout(app.MediesTab);
            app.LocaisderealizaesdasmediesdeRNILabel = uilabel(app.GridLayout6);
            app.LocaisderealizaesdasmediesdeRNILabel.Text = 'Locais de realizações das medições de RNI:';
            app.CalcMaisProx = uibutton(app.GridLayout6, 'push');
            app.CalcMaisProx.ButtonPushedFcn = createCallbackFcn(app, @CalcMaisProxButtonPushed, true);
            app.CalcMaisProx.Enable = 'off';
            app.CalcMaisProx.Text = 'Calcular maiores níveis E(V/m) - Distância 200 metros';
            app.TreeLocalRNI = uitree(app.GridLayout6, 'checkbox');
            % Assign Checked Nodes
            app.TreeLocalRNI.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeLocalRNICheckedNodesChanged, true);
            app.PA_RNITab = uitab(app.TabGroup);
            app.PA_RNITab.Title = 'PA_RNI';
            app.GridLayout2 = uigridlayout(app.PA_RNITab);
            app.ListBox_GR_UO = uilistbox(app.GridLayout2);
            app.ListBox_GR_UO.Items = {'GR01', 'GR02', 'GR03', 'GR04', 'GR05', 'GR06', 'GR07', 'GR08', 'GR09', 'GR10', 'GR11', 'UO001', 'UO021', 'UO031', 'UO061', 'UO062', 'UO071', 'UO072', 'UO073', 'UO081', 'UO091', 'UO092', 'UO101', 'UO111', 'UO112', 'UO113'};
            app.ListBox_GR_UO.Multiselect = 'on';
            app.ListBox_GR_UO.ValueChangedFcn = createCallbackFcn(app, @ListBox_GR_UOValueChanged, true);
            app.ListBox_GR_UO.Value = {};
            app.SelecioneasLocalidadesdesejadasLabel = uilabel(app.GridLayout2);
            app.SelecioneasLocalidadesdesejadasLabel.Text = 'Selecione a(s) Localidade(s) desejada(s):';
            app.CalcularmaioresnveisEVmButton = uibutton(app.GridLayout2, 'push');
            app.CalcularmaioresnveisEVmButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularmaioresnveisEVmButtonPushed, true);
            app.CalcularmaioresnveisEVmButton.Text = 'Calcular maiores níveis E(V/m)';
            app.DistnciamEditFieldLabel = uilabel(app.GridLayout2);
            app.DistnciamEditFieldLabel.Text = 'Distância (m):';
            app.DistEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DistEditField.Value = 200;
            app.Tree = uitree(app.GridLayout2, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.SelecioneosGRUOsdesejadosLabel = uilabel(app.GridLayout2);
            app.SelecioneosGRUOsdesejadosLabel.Text = 'Selecione o(s) GR/UO(s) desejado(s):';
            app.EstaoTab = uitab(app.TabGroup);
            app.EstaoTab.Title = 'Estação';
            app.GridLayout5 = uigridlayout(app.EstaoTab);
            app.HTML = uihtml(app.GridLayout5);
            app.HTML.HTMLSource = '''';
            app.MapaCheckBox = uicheckbox(app.GridLayout5);
            app.MapaCheckBox.Text = 'Mapa';
            app.TabelaCheckBox = uicheckbox(app.GridLayout5);
            app.TabelaCheckBox.Text = 'Tabela';
            app.ExibirButton = uibutton(app.GridLayout5, 'push');
            app.ExibirButton.ButtonPushedFcn = createCallbackFcn(app, @ExibirButtonPushed, true);
            app.ExibirButton.Text = 'Exibir';
            app.PontuaisTab = uitab(app.TabGroup);
            app.PontuaisTab.Title = 'Pontuais';
            app.GridLayout7 = uigridlayout(app.PontuaisTab);
            app.DemandaspontuaisMPFentidadesexternasetcLabel = uilabel(app.GridLayout7);
            app.DemandaspontuaisMPFentidadesexternasetcLabel.Text = 'Demandas pontuais (MPF, entidades externas etc)';
            app.NdaestaoLabel = uilabel(app.GridLayout7);
            app.NdaestaoLabel.Text = 'Nº da estação:';
            app.LocalidadeLabel = uilabel(app.GridLayout7);
            app.LocalidadeLabel.Text = 'Localidade:';
            app.ServioLabel = uilabel(app.GridLayout7);
            app.ServioLabel.Text = 'Serviço:';
            app.EditFieldNEstPont = uieditfield(app.GridLayout7, 'text');
            app.EditFieldLocalPont = uieditfield(app.GridLayout7, 'text');
            app.EditFieldServPont = uieditfield(app.GridLayout7, 'text');
            app.LatitudeLabel = uilabel(app.GridLayout7);
            app.LatitudeLabel.Text = 'Latitude:';
            app.LongitudeLabel = uilabel(app.GridLayout7);
            app.LongitudeLabel.Text = 'Longitude:';
            app.RaioaveriguadoLabel = uilabel(app.GridLayout7);
            app.RaioaveriguadoLabel.Text = 'Raio averiguado:';
            app.EditFieldLatPont = uieditfield(app.GridLayout7, 'text');
            app.EditFieldLongPont = uieditfield(app.GridLayout7, 'text');
            app.EditFieldRaioPont = uieditfield(app.GridLayout7, 'text');
            app.GerarKMLButton = uibutton(app.GridLayout7, 'push');
            app.GerarKMLButton.Text = 'Gerar KML';
            app.UFLabel = uilabel(app.GridLayout7);
            app.UFLabel.Text = 'UF:';
            app.EditFieldUFPont = uieditfield(app.GridLayout7, 'text');
            app.MaioresnveisButton_2 = uibutton(app.GridLayout7, 'push');
            app.MaioresnveisButton_2.Text = 'Maiores níveis';
            app.InserirnatabelaButton = uibutton(app.GridLayout7, 'push');
            app.InserirnatabelaButton.ButtonPushedFcn = createCallbackFcn(app, @InserirnatabelaButtonPushed, true);
            app.InserirnatabelaButton.Text = 'Inserir na tabela';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UIpanel = uipanel(app.GridLayout);
            app.SelecioneosarquivosdasmediessLabel = uilabel(app.GridLayout);
            app.SelecioneosarquivosdasmediessLabel.Text = 'Selecione o(s) arquivo(s) da(s) medições(s):';
            app.ImageOpenFile = uiimage(app.GridLayout);
            app.ImageOpenFile.ImageClickedFcn = createCallbackFcn(app, @ImageOpenFileClicked2, true);
            app.ImageOpenFile.ImageSource = fullfile(pathToMLAPP, 'files.png');
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppRNI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end