classdef dockStationInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        StationPanel            matlab.ui.container.GridLayout
        NextSelection           matlab.ui.control.Image
        PreviousSelection       matlab.ui.control.Image
        LocationPanel           matlab.ui.container.Panel
        LocationPanelGrid       matlab.ui.container.GridLayout
        Longitude               matlab.ui.control.NumericEditField
        LongitudeLabel          matlab.ui.control.Label
        Latitude                matlab.ui.control.NumericEditField
        LatitudeLabel           matlab.ui.control.Label
        LocationLabelGrid       matlab.ui.container.GridLayout
        LocationEditionCancel   matlab.ui.control.Image
        LocationEditionConfirm  matlab.ui.control.Image
        LocationEditionMode     matlab.ui.control.Image
        LocationRefresh         matlab.ui.control.Image
        LocationLabel           matlab.ui.control.Label
        optNotes                matlab.ui.control.TextArea
        optNotesLabel           matlab.ui.control.Label
        Reason                  matlab.ui.control.DropDown
        ReasonLabel             matlab.ui.control.Label
        Station                 matlab.ui.control.Label
        btnClose                matlab.ui.control.Image
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryDockApp'
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        Container
        isDocked = true
        mainApp
        callingApp
        jsBackDoor
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        projectData
    end
    
    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            drawnow

            elToModify = {app.Station};
            elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
    
            if ~isempty(elDataTag)
                appName = class.Constants.appName;
                ui.TextView.startup(app.jsBackDoor, elToModify{1}, appName);
            end
        end

        %-----------------------------------------------------------------%
        function initialValues(app)
            app.Reason.Items = categories(app.projectData.modules.MonitoringPlan.stationTable.("Justificativa"));
            updateForm(app)
        end

        %-----------------------------------------------------------------%
        function updateForm(app, varargin)
            if isempty(varargin)
                idxStation = selectedStation(app);
            else
                idxStation = varargin{1};
            end

            updateFormValues(app, idxStation)
            updateFormLayout(app, idxStation)
        end
        
        %-----------------------------------------------------------------%
        function updateFormValues(app, idxStation)
            app.Station.Text   = util.HtmlTextGenerator.StationInfo(app.projectData.modules.MonitoringPlan.stationTable, idxStation, app.mainApp.rfDataHub);
            app.Reason.Value   = char(app.projectData.modules.MonitoringPlan.stationTable.("Justificativa")(idxStation));
            app.optNotes.Value = app.projectData.modules.MonitoringPlan.stationTable.("Observações"){idxStation};

            set(app.Latitude,  'Value', app.projectData.modules.MonitoringPlan.stationTable.("Latitude")(idxStation),  'UserData', app.projectData.modules.MonitoringPlan.stationTable.("Latitude")(idxStation))
            set(app.Longitude, 'Value', app.projectData.modules.MonitoringPlan.stationTable.("Longitude")(idxStation), 'UserData', app.projectData.modules.MonitoringPlan.stationTable.("Longitude")(idxStation))            
        end

        %-----------------------------------------------------------------%
        function updateFormLayout(app, idxStation)
            % Identifica se ocorreu alteração nas coordenadas geográficas:
            if (app.projectData.modules.MonitoringPlan.stationTable.("Lat")(idxStation)  ~= app.projectData.modules.MonitoringPlan.stationTable.("Latitude")(idxStation)) || ...
               (app.projectData.modules.MonitoringPlan.stationTable.("Long")(idxStation) ~= app.projectData.modules.MonitoringPlan.stationTable.("Longitude")(idxStation))
                app.LocationRefresh.Visible = 1;
            else
                app.LocationRefresh.Visible = 0;
            end

            updateLocationPanelLayout(app, 'off')
        end

        %-----------------------------------------------------------------%
        function updateLocationPanelLayout(app, editionStatus)
            arguments
                app 
                editionStatus char {mustBeMember(editionStatus, {'on', 'off'})}
            end

            hEditFields = findobj(app.LocationPanelGrid.Children, '-not', 'Type', 'uilabel');            

            switch editionStatus
                case 'on'
                    set(app.LocationEditionMode, 'ImageSource', 'Edit_32Filled.png', 'UserData', true)
                    set(hEditFields, 'Editable', true)
                    
                    app.LocationLabelGrid.ColumnWidth(end-1:end) = {18, 18};
                    app.LocationEditionConfirm.Enable = 1;
                    app.LocationEditionCancel.Enable  = 1;

                case 'off'
                    set(app.LocationEditionMode, 'ImageSource', 'Edit_32.png', 'UserData', false)
                    set(hEditFields, 'Editable', false)

                    app.LocationLabelGrid.ColumnWidth(end-1:end) = {0,0};
                    app.LocationEditionConfirm.Enable = 0;
                    app.LocationEditionCancel.Enable  = 0;
            end
        end

        %-----------------------------------------------------------------%
        function [idxStation, idxRow] = selectedStation(app)
            idxRow     = app.callingApp.UITable.Selection;
            idxStation = app.callingApp.UITable.UserData(idxRow);
        end

        %-----------------------------------------------------------------%
        function CallingMainApp(app, callType, updateFlag, returnFlag, varargin)
            ipcSecundaryMatlabCallsHandler(app.callingApp, app, callType, updateFlag, returnFlag, varargin{:})
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
                        
            try
                appEngine.boot(app, app.Role, callingApp.mainApp, callingApp)

                app.inputArgs   = struct('context', context, 'index', index);
                app.projectData = callingApp.mainApp.projectData;
    
                jsBackDoor_Customizations(app)
                initialValues(app)
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Callback function: UIFigure, btnClose
        function closeFcn(app, event)
            
            CallingMainApp(app, 'closeFcn', false, false)
            delete(app)
            
        end

        % Value changed function: Reason, optNotes
        function FormValueChanged(app, event)
            
            switch event.Source
                case {app.Reason, app.optNotes}
                    newReason    = app.Reason.Value;
                    newOptNote   = textFormatGUI.cellstr2TextField(app.optNotes.Value);
                    CallingMainApp(app, 'StationTableValueChanged: ReasonOrNote', true, true, newReason, newOptNote)

                case {app.LocationRefresh, app.LocationEditionConfirm}
                    if isequal(app.Latitude.Value,  app.Latitude.UserData) && ...
                       isequal(app.Longitude.Value, app.Longitude.UserData)
                        return
                    end

                    newLatitude  = app.Latitude.Value;
                    newLongitude = app.Longitude.Value;
                    CallingMainApp(app, 'MonitoringPlan:StationCoordinatesEdited', true, true, newLatitude, newLongitude)
            end
            
            updateForm(app)
            
        end

        % Image clicked function: LocationEditionCancel, 
        % ...and 3 other components
        function LocationEditionModeCallbacks(app, event)
            
            switch event.Source
                case app.LocationRefresh
                    idxStation = selectedStation(app);
                    app.Latitude.Value  = app.projectData.modules.MonitoringPlan.stationTable.("Lat")(idxStation);
                    app.Longitude.Value = app.projectData.modules.MonitoringPlan.stationTable.("Long")(idxStation);

                    FormValueChanged(app, struct('Source', event.Source))

                case app.LocationEditionMode
                    app.LocationEditionMode.UserData = ~app.LocationEditionMode.UserData;
                    if app.LocationEditionMode.UserData
                        updateLocationPanelLayout(app, 'on')
                        LocationValueChanged(app, struct('Source', event.Source))
                    else
                        updateLocationPanelLayout(app, 'off')
                        updateForm(app)
                    end

                case app.LocationEditionConfirm
                    FormValueChanged(app, struct('Source', event.Source))

                case app.LocationEditionCancel
                    updateForm(app)
            end

        end

        % Value changed function: Latitude, Longitude
        function LocationValueChanged(app, event)
            
            switch event.Source
                case app.LocationEditionMode
                    focus(app.Latitude)
                case app.Latitude
                    focus(app.Longitude)
                case app.Longitude
                    focus(app.LocationEditionConfirm)
            end

        end

        % Image clicked function: NextSelection, PreviousSelection
        function UITableSelectionChanged(app, event)
            
            idxStations = app.callingApp.UITable.UserData;
            [~, idxSelectedRow] = selectedStation(app);
            idxMaxRow = height(app.callingApp.UITable.Data);

            switch event.Source
                case app.PreviousSelection
                    idxNewRowSelection = idxSelectedRow - 1;
                case app.NextSelection
                    idxNewRowSelection = idxSelectedRow + 1;
            end

            if idxNewRowSelection < 1
                idxNewRowSelection = idxMaxRow;
            elseif idxNewRowSelection > idxMaxRow
                idxNewRowSelection = 1;
            end

            CallingMainApp(app, 'UITableSelectionChanged', true, true, idxNewRowSelection)
            updateForm(app, idxStations(idxNewRowSelection))

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorRNI';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.btnClose = uiimage(app.GridLayout);
            app.btnClose.ImageClickedFcn = createCallbackFcn(app, @closeFcn, true);
            app.btnClose.Tag = 'Close';
            app.btnClose.ImageSource = 'Delete_12SVG.svg';
            app.StationPanel = uigridlayout(app.GridLayout);
            app.Station = uilabel(app.StationPanel);
            app.Station.Interpreter = 'html';
            app.Station.Text = '';
            app.ReasonLabel = uilabel(app.StationPanel);
            app.ReasonLabel.Text = 'Justificativa:';
            app.Reason = uidropdown(app.StationPanel);
            app.Reason.Items = {};
            app.Reason.ValueChangedFcn = createCallbackFcn(app, @FormValueChanged, true);
            app.Reason.Value = {};
            app.optNotesLabel = uilabel(app.StationPanel);
            app.optNotesLabel.Text = 'Observações:';
            app.optNotes = uitextarea(app.StationPanel);
            app.optNotes.ValueChangedFcn = createCallbackFcn(app, @FormValueChanged, true);
            app.LocationLabelGrid = uigridlayout(app.StationPanel);
            app.LocationLabel = uilabel(app.LocationLabelGrid);
            app.LocationLabel.Text = 'Local de instalação:';
            app.LocationRefresh = uiimage(app.LocationLabelGrid);
            app.LocationRefresh.ImageClickedFcn = createCallbackFcn(app, @LocationEditionModeCallbacks, true);
            app.LocationRefresh.Visible = 'off';
            app.LocationRefresh.Tooltip = {'Retorna à informação inicial'; '(extraída da tabela de referência)'};
            app.LocationRefresh.ImageSource = 'Refresh_18.png';
            app.LocationEditionMode = uiimage(app.LocationLabelGrid);
            app.LocationEditionMode.ImageClickedFcn = createCallbackFcn(app, @LocationEditionModeCallbacks, true);
            app.LocationEditionMode.Tooltip = {'Habilita painel de edição'};
            app.LocationEditionMode.ImageSource = 'Edit_32.png';
            app.LocationEditionConfirm = uiimage(app.LocationLabelGrid);
            app.LocationEditionConfirm.ImageClickedFcn = createCallbackFcn(app, @LocationEditionModeCallbacks, true);
            app.LocationEditionConfirm.Enable = 'off';
            app.LocationEditionConfirm.Tooltip = {'Confirma edição'};
            app.LocationEditionConfirm.ImageSource = 'Ok_32Green.png';
            app.LocationEditionCancel = uiimage(app.LocationLabelGrid);
            app.LocationEditionCancel.ImageClickedFcn = createCallbackFcn(app, @LocationEditionModeCallbacks, true);
            app.LocationEditionCancel.Enable = 'off';
            app.LocationEditionCancel.Tooltip = {'Cancela edição'};
            app.LocationEditionCancel.ImageSource = 'Delete_32Red.png';
            app.LocationPanel = uipanel(app.StationPanel);
            app.LocationPanelGrid = uigridlayout(app.LocationPanel);
            app.LatitudeLabel = uilabel(app.LocationPanelGrid);
            app.LatitudeLabel.Text = 'Latitude:';
            app.Latitude = uieditfield(app.LocationPanelGrid, 'numeric');
            app.Latitude.Limits = [-90 90];
            app.Latitude.ValueDisplayFormat = '%.6f';
            app.Latitude.ValueChangedFcn = createCallbackFcn(app, @LocationValueChanged, true);
            app.Latitude.Editable = 'off';
            app.LongitudeLabel = uilabel(app.LocationPanelGrid);
            app.LongitudeLabel.Text = 'Longitude:';
            app.Longitude = uieditfield(app.LocationPanelGrid, 'numeric');
            app.Longitude.Limits = [-180 180];
            app.Longitude.ValueDisplayFormat = '%.6f';
            app.Longitude.ValueChangedFcn = createCallbackFcn(app, @LocationValueChanged, true);
            app.Longitude.Editable = 'off';
            app.PreviousSelection = uiimage(app.StationPanel);
            app.PreviousSelection.ImageClickedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.PreviousSelection.Tooltip = {'Navega para o produto anterior'};
            app.PreviousSelection.ImageSource = 'Previous_32.png';
            app.NextSelection = uiimage(app.StationPanel);
            app.NextSelection.ImageClickedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.NextSelection.Tooltip = {'Navega para o produto posterior'};
            app.NextSelection.ImageSource = 'After_32.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dockStationInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end